classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        Image       matlab.ui.control.Image
        Button      matlab.ui.control.Button
        Button_2    matlab.ui.control.Button
        RightPanel  matlab.ui.container.Panel
        Label       matlab.ui.control.Label
        TextArea    matlab.ui.control.TextArea
        Image2      matlab.ui.control.Image
        UIAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            close all
            [filename,filepath]=uigetfile({'*.bmp;*.jpg'},'选择图片文件');
            file=fullfile(filepath, filename);
            imshow(file,'Parent',app.UIAxes);
            app.Image2.Visible = 'off';
            app.UIAxes.Visible = 'on';
            set(app.Button,'userdata',file);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %读入彩色图像
            I = imread(app.Button.UserData);    
            
            %================图像分割区域(车牌定位)==========================
            plate =image_segmentation1(I);

            imshow(plate,'Parent',app.UIAxes);
            pause(1);
            %========================倾斜校正=================
            
            [picture_1,angle] = rando_bianhuan(plate); %倾斜校正  picture 返回校正后的图片 angle 返回倾斜角度
            imshow(picture_1,'Parent',app.UIAxes);
            pause(1);
            %====================去除边框=========================
            
            bw = qubian(picture_1);
            imshow(bw,'Parent',app.UIAxes);
            pause(1);
            %=================文字分割 ===================================
            
            image=qiege(bw);
            %=================显示分割图像结果================================
            bb =zifu_shibie(image);
            app.TextArea.Value=bb;
            %语音播报
%             duchushengyin(bb);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {473, 473};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'LOGO.jpg';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择图片';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '开始识别';
            app.RightPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '识别结果';
            app.TextArea = uitextarea(app.RightPanel);
            app.Image2 = uiimage(app.RightPanel);
            app.Image2.ImageSource = 'unknown.jpg';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '待识别图象')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end